classdef app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_4                     matlab.ui.control.Label
        epochLabel                  matlab.ui.control.Label
        Panel2                      matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        SYSEditField                matlab.ui.control.EditField
        SYSEditFieldLabel           matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        degLabel_4                  matlab.ui.control.Label
        kmLabel                     matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        minelevationEditField       matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        latEditField                matlab.ui.control.NumericEditField
        latEditFieldLabel           matlab.ui.control.Label
        lonEditField                matlab.ui.control.NumericEditField
        lonEditFieldLabel           matlab.ui.control.Label
        HEditField                  matlab.ui.control.NumericEditField
        Slider                      matlab.ui.control.Slider
        HEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        nav, t0, tf, ep, tm, t1, lon, lat, HE, az, el, E, N, 
        U, sat_lat, sat_lon, sat_HE, gs, sat, orbit, ac, gt, gx;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load('nav.mat')
            app.nav = nav;
            if app.SYSEditField.Value == "GPS"
                app.t0 = datetime(app.nav.GPS.toc);
                app.Label_3.Text = string(app.t0);
                app.tf = datetime(app.nav.GPS.toc) + days(1);
                app.Label_2.Text = string(app.tf);
                app.ep = app.t0 + seconds(app.Slider.Value);
                app.Label_4.Text = string(app.ep);
            end
            if app.SYSEditField.Value == "QZSS"
                app.t0 = datetime(app.nav.QZSS.toc);
                app.Label_3.Text = string(app.t0);
                app.tf = datetime(app.nav.QZSS.toc) + days(1);
                app.Label_2.Text = string(app.tf);
                app.ep = app.t0 + seconds(app.Slider.Value);
                app.Label_4.Text = string(app.ep);
            end
            if app.SYSEditField.Value == "BDS"
                app.t0 = datetime(app.nav.BDS.toc);
                app.Label_3.Text = string(app.t0);
                app.tf = datetime(app.nav.BDS.toc) + days(1);
                app.Label_2.Text = string(app.tf);
                app.ep = app.t0 + seconds(app.Slider.Value);
                app.Label_4.Text = string(app.ep);
            end

            app.tm = app.t0 : seconds(300) : app.ep;

            for i = 1 : size(app.tm , 2)
                [y, m, d] = ymd(app.tm(i));
                [h1, m1, s1] = hms(app.tm(i));
                app.t1 = [y , m, d, h1, m1, s1];
                [r_eci(:,i)] = toECI(app.SYSEditField.Value, app.nav, app.t1);
                [r_ecef(:,i)] = eci2ecef(app.t1, r_eci(:,i));
            end
            

            app.lon = app.lonEditField.Value;
            app.lat = app.latEditField.Value;
            app.HE = app.HEditField.Value;

            wgs84 = wgs84Ellipsoid('kilometer');
            [app.sat_lat, app.sat_lon, app.sat_HE] = ecef2geodetic(wgs84, r_ecef(1,:), r_ecef(2,:), r_ecef(3,:));

            [app.E, app.N, app.U] = ecef2enu(r_ecef(1,:), r_ecef(2,:), r_ecef(3,:), app.lat, app.lon, app.HE, wgs84);
            app.az = azimuth([app.E', app.N', app.U']);
            app.el = elevation([app.E', app.N', app.U'], app.minelevationEditField.Value);

            for j = 1 : length(app.el)
                for k = 1 : length(app.el)
                    if isnan(app.el(k))
                        app.el(k) = NaN;
                        app.az(k) = NaN;
                    end
                end
            end

            app.el = rmmissing(app.el);
            app.az = rmmissing(app.az);

            skyplot(app.Panel, app.az', app.el')

            app.gx = geoaxes(app.Panel2);
            geoplot(app.gx, app.sat_lat, app.sat_lon, '.');

            app.orbit = satelliteScenario(app.t0, app.tf, 300);
            app.gs = groundStation(app.orbit, app.lat, app.lon);
            para = hj(app.t1, app.nav, app.SYSEditField.Value);
            app.sat = satellite(app.orbit, para(1)*1000, para(2), para(3), para(4), para(5), para(6));
            app.ac = access(app.sat,app.gs);
            leadTime = 2*24*3600;                                          
            trailTime = leadTime;
            app.gt=groundTrack(app.sat,"LeadTime",leadTime,"TrailTime",trailTime);
            play(app.orbit)
          
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 86400];
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [];
            app.Slider.Value = 86400;
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.Value = 1;
            app.lonEditFieldLabel = uilabel(app.UIFigure);
            app.lonEditFieldLabel.Text = 'lon';
            app.lonEditField = uieditfield(app.UIFigure, 'numeric');
            app.lonEditField.Value = 127;
            app.latEditFieldLabel = uilabel(app.UIFigure);
            app.latEditFieldLabel.Text = 'lat';
            app.latEditField = uieditfield(app.UIFigure, 'numeric');
            app.latEditField.Value = 37;
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.minelevationEditField.Value = 10;
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.degLabel_4 = uilabel(app.UIFigure);
            app.degLabel_4.Text = 'deg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SYSEditFieldLabel = uilabel(app.UIFigure);
            app.SYSEditFieldLabel.Text = 'SYS';
            app.SYSEditField = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.epochLabel = uilabel(app.UIFigure);
            app.epochLabel.Text = 'epoch';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end